classdef EMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        SerialLabel         matlab.ui.control.Label
        T_SerialNumber      matlab.ui.control.TextArea
        B_SerialConnect     matlab.ui.control.Button
        F_Dispaly           matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        L_datalist          matlab.ui.control.Table
        B_GenerateData      matlab.ui.control.Button
        LabelDropDownLabel  matlab.ui.control.Label
        LabelDropDown       matlab.ui.control.DropDown
        L_ModelList         matlab.ui.control.Table
        B_Control           matlab.ui.control.Button
        UIAxes_3            matlab.ui.control.UIAxes
        B_Control_2         matlab.ui.control.Button
        Tab_2               matlab.ui.container.Tab
        ch1                 matlab.ui.control.UIAxes
        RawDataList         matlab.ui.control.Table
        showdata            matlab.ui.control.Button
        ch2                 matlab.ui.control.UIAxes
        ch3                 matlab.ui.control.UIAxes
        allch               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = refresh_list(app)
            load('data.mat');
            %app.L_datalist.Data={'d2' length(d2);'d4' length(d4);'d5' length(d5);'d6' length(d6);'d8' length(d8)}
            indata=struct2cell(whos('-file','data.mat'))';
            indata(:,4)={logical(0)};
            %app.L_datalist.ColumnFormat={'numeric','numeric','numeric','logical'}
            app.L_datalist.Data = cell2table(indata(:,1:4));
            
        end
        
        function results = refresh_ModelList(app)
            ModelGUI=load('ModelGUI.mat');
            %app.L_datalist.Data={'d2' length(d2);'d4' length(d4);'d5' length(d5);'d6' length(d6);'d8' length(d8)}
            indata=struct2cell(whos('-file','ModelGUI.mat'))';
            indata(:,3)={logical(0)};
            
            
            field = fieldnames(ModelGUI); % cell
            
            for i = 1:length(field)
                
                name_i = field{i}
                
                
                indata(i,2) = {ModelGUI.(name_i).Percent} % 方式三
                
            end
            
            
            %app.L_datalist.ColumnFormat={'numeric','numeric','numeric','logical'}
            app.L_ModelList.Data = cell2table(indata(:,1:3));
        end
        
        function results = refresh_rawlist(app)
            Rlist=load('RawData.mat');
            indata=struct2cell(whos('-file','RawData.mat'))';
            indata(:,4)={logical(0)};


            %app.L_datalist.ColumnFormat={'numeric','numeric','numeric','logical'}
            app.RawDataList.Data = cell2table(indata(:,1:4));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            refresh_list(app);
            refresh_ModelList(app);
            refresh_rawlist(app);
        end

        % Cell selection callback: L_datalist
        function L_datalistCellSelection(app, event)
            indices = event.Indices;
        end

        % Button pushed function: B_SerialConnect
        function B_SerialConnectButtonPushed(app, event)
            delete(instrfindall);
            SerialNumber=cell2mat(app.T_SerialNumber.Value);
            s = serial(SerialNumber); %定义串口对象
            set(s,'BaudRate',115200); %设置波特率s
            fopen(s); %打开串口对象s
            interval = 300; %这里开始的代码很Part1里的代码类似
            passo = 1;
            t = 1;
            x = 0;
            k=[];
            buffer=[0;0;0];
            buffer2=0;
            rawData=[0;0;0];
            while(t<interval)
                b = str2num(fgetl(s)); %用函数fget(s)从缓冲区读取串口数据，当出现终止符（换行符）停止。
                if length(b)~=0%开始接收数据
                    for i=[1:100]%采样20个数据
                        b = str2num(fgetl(s));
                        if length(b~=0)
                            DATA(i,1:3)=b(1:3);%数据按序列放入DATA待处理
                        end
                    end
                    
                    %循环完成，对100点进行处理
                    f1(1)= jfemg('emav', DATA(:,1));
                    f1(2) = jfemg('emav', DATA(:,2));
                    f1(3) = jfemg('emav', DATA(:,3));
                    
                    %    Average Amplitude Change
                    f2(1) = jfemg('aac', DATA(:,1));
                    f2(2) = jfemg('aac', DATA(:,2));
                    f2(3) = jfemg('aac', DATA(:,3));
                    
                    %   Waveform Length
                    f3(1) = jfemg('wl',DATA(:,1));
                    f3(2) = jfemg('wl',DATA(:,2));
                    f3(3) = jfemg('wl',DATA(:,3));
                    
                    %  Maximum Fractal Length
                    f4(1) = jfemg('mfl', DATA(:,1));
                    f4(2) = jfemg('mfl', DATA(:,2));
                    f4(3) = jfemg('mfl', DATA(:,3));
                    
                    % Root Mean Square
                    f5(1) = jfemg('rms', DATA(:,1));
                    f5(2) = jfemg('rms', DATA(:,2));
                    f5(3) = jfemg('rms', DATA(:,3));
                    f5(4)=str2num(app.LabelDropDown.Value)
                    k=[k;[f1 f2 f3 f4 f5]];
                    %合并得到特征
                    %label(1:100)=(trainedModelyn.predictFcn(k));
                    buffer=[buffer DATA'];
                    %buffer2=[buffer2 label];
                    plot(app.F_Dispaly,buffer')
                    rawData=[rawData DATA']%记得加'，表示转置！
                    
                    %[x,y]=train(trainedModel5.predictFcn([f1 f2 f3 f4 f5]));
                    %                     fill(x,y,'r');  %用r：红色进行填充
                    %                     axis([0 3 0 3]);%让坐标横纵轴都显示在0-3范围内
                    
                    %         x(1:100)=trainedModel6.predictFcn([f1 f2 f3 f4 f5])
                    %         buffer2=[buffer2 x];
                    %         plot(buffer2)
                    
                    drawnow;
                    %y_trainer()%根据预测标签，使用ctrl函数进行方向控制
                end
                t = t+passo;
            end
            sound(sin(2*pi*25*(1:4000)/100));
            
            mconfirm={'保存此次数据？'};
            
            if uiconfirm(app.UIFigure,mconfirm,'确认') == '取消';
                return;
            end
            TIME=datetime("now","Format","uuuuMMdd'T'HHmmss");
            name=['d' app.LabelDropDown.Value '_' char(TIME)];%特征名：d(标签)_(时间)
            eval( [name, '= k']);                             %特证名变量=特征序列
            
            name=['Raw' app.LabelDropDown.Value '_' char(TIME)];%源数据名：Raw(标签)_(时间)
            eval([name, '= rawData']);
            save('data.mat','d*','-append')
            save('RawData.mat','Raw*','-append')
            
            refresh_list(app);
            refresh_rawlist(app);
            % save('data.mat',"k")
        end

        % Button pushed function: B_GenerateData
        function B_GenerateDataButtonPushed(app, event)
            D=load('data.mat');
            assignin('base','aa',app.L_datalist.Data);%第一个地方就填'base'
            Logic1DataName=cell2mat(app.L_datalist.Data.Var1(find(app.L_datalist.Data.Var4==1)'));
            %eval( '', '= k']);
            DATA=[];
            for i=1:length(Logic1DataName(:,1))
                DATA=[DATA;D.(Logic1DataName(i,:))];
            end
            assignin('base','DATA',DATA);%第一个地方就填'base'
            [A B]=LinearSVM(DATA)
            ModelName=['Model' char(datetime("now","Format","uuuuMMdd'T'HHmmss"))];
            eval([ModelName, '= A']);
            
            eval([[ModelName '.Percent'], '= B']);
            save('ModelGUI.mat',"Model2*",'-append')
            load('ModelGUI.mat')
            refresh_ModelList(app);
        end

        % Button pushed function: B_Control
        function B_ControlButtonPushed(app, event)
            %             delete(instrfindall);
            %             SerialNumber=cell2mat(app.T_SerialNumber.Value)
            %             s = serial(SerialNumber); %定义串口对象
            %             set(s,'BaudRate',115200); %设置波特率s
            %             fopen(s); %打开串口对象s
            %             ModelGUI=load('ModelGUI.mat');
            %             interval = 1000; %这里开始的代码很Part1里的代码类似
            %             passo = 1;
            %             t = 1;
            %             x = 0;
            %             k=[];
            %             buffer=[0;0;0];
            %             buffer2=0;
            %             i=app.L_ModelList.Data
            %             modelnumb=find(i.Var3==1);
            %             %assignin('base','i',app.L_ModelList.Data);%第一个地方就填'base'
            %             %                     modelnumb=filed(find(i.Var3==1))
            %             %                     trainedModel=ModelGUI.filed{modelnumb};
            %             field = fieldnames(ModelGUI);
            %             name_i=field{modelnumb};
            %             trainedModel=getfield(ModelGUI, name_i);
            %             assignin('base','trainedModel',trainedModel);%第一个地方就填'base'
            %
            %             while(t<interval)
            %                 b = str2num(fgetl(s)); %用函数fget(s)从缓冲区读取串口数据，当出现终止符（换行符）停止。
            %                 if length(b)~=0%开始接收数据
            %                     for i=[1:100]%采样20个数据
            %                         b = str2num(fgetl(s));
            %                         DATA(i,1:3)=b(1:3);%数据按序列放入DATA待处理
            %                     end
            %
            %                     %循环完成，对100点进行处理
            %                     f1(1)= jfemg('emav', DATA(:,1));
            %                     f1(2) = jfemg('emav', DATA(:,2));
            %                     f1(3) = jfemg('emav', DATA(:,3));
            %
            %                     %    Average Amplitude Change
            %                     f2(1) = jfemg('aac', DATA(:,1));
            %                     f2(2) = jfemg('aac', DATA(:,2));
            %                     f2(3) = jfemg('aac', DATA(:,3));
            %
            %                     %   Waveform Length
            %                     f3(1) = jfemg('wl',DATA(:,1));
            %                     f3(2) = jfemg('wl',DATA(:,2));
            %                     f3(3) = jfemg('wl',DATA(:,3));
            %
            %                     %  Maximum Fractal Length
            %                     f4(1) = jfemg('mfl', DATA(:,1));
            %                     f4(2) = jfemg('mfl', DATA(:,2));
            %                     f4(3) = jfemg('mfl', DATA(:,3));
            %
            %                     % Root Mean Square
            %                     f5(1) = jfemg('rms', DATA(:,1));
            %                     f5(2) = jfemg('rms', DATA(:,2));
            %                     f5(3) = jfemg('rms', DATA(:,3));
            %                     %合并得到特征
            %                     %label(1:100)=(trainedModelyn.predictFcn(k));
            %                     buffer=[buffer DATA'];
            %                     %buffer2=[buffer2 label];
            %                     plot(app.F_Dispaly,buffer')
            %
            %
            %
            % %                     for i = 1:length(field)
            % %
            % %                         name_i = field{i};
            % %
            % %
            % %                         indata(i,2) = {ModelGUI.(name_i).percent} % 方式三
            % %
            % %                     end
            %                     LABELGET(1:100)=trainedModel.predictFcn([f1 f2 f3 f4 f5]);
            %                     buffer2=[buffer2 LABELGET];
            %                     [x,y]=train(LABELGET(1));
            %
            %                     plot(app.UIAxes_3,buffer2)
            %
            %
            %                     fill(app.UIAxes_2,x,y,'r');  %用r：红色进行填充
            %                     axis(app.UIAxes_2,[0 3 0 3]);%让坐标横纵轴都显示在0-3范围内
            %                     drawnow
            %
            %                     %         x(1:100)=trainedModel6.predictFcn([f1 f2 f3 f4 f5])
            %                     %         buffer2=[buffer2 x];
            %                     %         plot(buffer2)
            %
            %                     %y_trainer()%根据预测标签，使用ctrl函数进行方向控制
            %                 end
            %                 t = t+passo;
            %             end
            %
            %             % save('data.mat',"k")
            delete(instrfindall);
            SerialNumber=cell2mat(app.T_SerialNumber.Value)
            s = serial(SerialNumber); %定义串口对象
            set(s,'BaudRate',115200); %设置波特率s
            fopen(s); %打开串口对象s
            ModelGUI=load('ModelGUI.mat');
            interval = 1000; %这里开始的代码很Part1里的代码类似
            
            passo = 1;
            t = 1;
            x = 0;
            k=[];
            buffer=[0;0;0];
            buffer2=0;
            rawData=[0;0;0];
            
            i=app.L_ModelList.Data
            modelnumb=find(i.Var3==1);
            
            field = fieldnames(ModelGUI);
            name_i=field{modelnumb};
            trainedModel=getfield(ModelGUI, name_i);
            assignin('base','trainedModel',trainedModel);%第一个地方就填'base'
            
            while(t<interval)
                b = str2num(fgetl(s)); %用函数fget(s)从缓冲区读取串口数据，当出现终止符（换行符）停止。
                if length(b)~=0%开始接收数据
                    for i=[1:100]%采样20个数据
                        b = str2num(fgetl(s));
                        if length(b~=0)
                            DATA(i,1:3)=b(1:3);%数据按序列放入DATA待处理
                        end
                    end
                    
                    %循环完成，对100点进行处理
                    f1(1)= jfemg('emav', DATA(:,1));
                    f1(2) = jfemg('emav', DATA(:,2));
                    f1(3) = jfemg('emav', DATA(:,3));
                    
                    %    Average Amplitude Change
                    f2(1) = jfemg('aac', DATA(:,1));
                    f2(2) = jfemg('aac', DATA(:,2));
                    f2(3) = jfemg('aac', DATA(:,3));
                    
                    %   Waveform Length
                    f3(1) = jfemg('wl',DATA(:,1));
                    f3(2) = jfemg('wl',DATA(:,2));
                    f3(3) = jfemg('wl',DATA(:,3));
                    
                    %  Maximum Fractal Length
                    f4(1) = jfemg('mfl', DATA(:,1));
                    f4(2) = jfemg('mfl', DATA(:,2));
                    f4(3) = jfemg('mfl', DATA(:,3));
                    
                    % Root Mean Square
                    f5(1) = jfemg('rms', DATA(:,1));
                    f5(2) = jfemg('rms', DATA(:,2));
                    f5(3) = jfemg('rms', DATA(:,3));
                    %合并得到特征
                    %label(1:100)=(trainedModelyn.predictFcn(k));
                    buffer=[buffer DATA'];
                    %buffer2=[buffer2 label];
                    plot(app.F_Dispaly,buffer')
                    rawData=[rawData DATA']%记得加'，表示转置！
                    
                    
                    %                     for i = 1:length(field)
                    %
                    %                         name_i = field{i};
                    %
                    %
                    %                         indata(i,2) = {ModelGUI.(name_i).percent} % 方式三
                    %
                    %                     end
                    LABELGET(1:100)=trainedModel.predictFcn([f1 f2 f3 f4 f5]);
                    buffer2=[buffer2 LABELGET];
                    [x,y]=train(LABELGET(1));
                    
                    plot(app.UIAxes_3,buffer2)
                    
                    
                    fill(app.UIAxes_2,x,y,'r');  %用r：红色进行填充
                    axis(app.UIAxes_2,[0 3 0 3]);%让坐标横纵轴都显示在0-3范围内
                    drawnow
                    
                    %         x(1:100)=trainedModel6.predictFcn([f1 f2 f3 f4 f5])
                    %         buffer2=[buffer2 x];
                    %         plot(buffer2)
                    
                    %y_trainer()%根据预测标签，使用ctrl函数进行方向控制
                end
                t = t+passo;
            end
            TIME=datetime("now","Format","uuuuMMdd'T'HHmmss");
            name=['Raw' 'T_' char(TIME)];%源数据名：Raw(标签)_(时间)
            eval([name, '= rawData']);
            save('RawData.mat','Raw*','-append')
            refresh_rawlist(app);
            
            % save('data.mat',"k")
        end

        % Button pushed function: B_Control_2
        function keyboard_ctri(app, event)
            delete(instrfindall);
            SerialNumber=cell2mat(app.T_SerialNumber.Value)
            s = serial(SerialNumber); %定义串口对象
            set(s,'BaudRate',115200); %设置波特率s
            fopen(s); %打开串口对象s
            ModelGUI=load('ModelGUI.mat');
            interval = 1000; %这里开始的代码很Part1里的代码类似
            passo = 1;
            t = 1;
            x = 0;
            k=[];
            buffer=[0;0;0];
            buffer2=0;
            i=app.L_ModelList.Data
            modelnumb=find(i.Var3==1);
            %assignin('base','i',app.L_ModelList.Data);%第一个地方就填'base'
            %                     modelnumb=filed(find(i.Var3==1))
            %                     trainedModel=ModelGUI.filed{modelnumb};
            field = fieldnames(ModelGUI);
            name_i=field{modelnumb};
            trainedModel=getfield(ModelGUI, name_i);
            assignin('base','trainedModel',trainedModel);%第一个地方就填'base'
            
            while(t<interval)
                b = str2num(fgetl(s)); %用函数fget(s)从缓冲区读取串口数据，当出现终止符（换行符）停止。
                if length(b)~=0%开始接收数据
                    for i=[1:100]%采样20个数据
                        b = str2num(fgetl(s));
                        DATA(i,1:3)=b(1:3);%数据按序列放入DATA待处理
                    end
                    
                    %循环完成，对100点进行处理
                    f1(1)= jfemg('emav', DATA(:,1));
                    f1(2) = jfemg('emav', DATA(:,2));
                    f1(3) = jfemg('emav', DATA(:,3));
                         
                    %    Average Amplitude Change
                    f2(1) = jfemg('aac', DATA(:,1));
                    f2(2) = jfemg('aac', DATA(:,2));
                    f2(3) = jfemg('aac', DATA(:,3));
                    
                    %   Waveform Length
                    f3(1) = jfemg('wl',DATA(:,1));
                    f3(2) = jfemg('wl',DATA(:,2));
                    f3(3) = jfemg('wl',DATA(:,3));
                    
                    %  Maximum Fractal Length
                    f4(1) = jfemg('mfl', DATA(:,1));
                    f4(2) = jfemg('mfl', DATA(:,2));
                    f4(3) = jfemg('mfl', DATA(:,3));
                    
                    % Root Mean Square
                    f5(1) = jfemg('rms', DATA(:,1));
                    f5(2) = jfemg('rms', DATA(:,2));
                    f5(3) = jfemg('rms', DATA(:,3));
                    %合并得到特征
                    %label(1:100)=(trainedModelyn.predictFcn(k));
                    buffer=[buffer DATA'];
                    %buffer2=[buffer2 label];
                    plot(app.F_Dispaly,buffer')


                    %                     for i = 1:length(field)
                    %
                    %                         name_i = field{i};
                    %
                    %
                    %                         indata(i,2) = {ModelGUI.(name_i).percent} % 方式三
                    %
                    %                     end
                    
                    drawnow
                    ctrl_y(trainedModel.predictFcn([f1 f2 f3 f4 f5]))
                    %         x(1:100)=trainedModel6.predictFcn([f1 f2 f3 f4 f5])
                    %         buffer2=[buffer2 x];
                    %         plot(buffer2)
                    
                    %y_trainer()%根据预测标签，使用ctrl函数进行方向控制
                end
                t = t+passo;
            end
            
            % save('data.mat',"k")
        end

        % Button pushed function: showdata
        function ShowData(app, event)
            rawData=load('RawData.mat');
            i=app.RawDataList.Data;
            datanumb=find(i.Var4==1);
            %assignin('base','i',app.L_ModelList.Data);%第一个地方就填'base'
            %                     modelnumb=filed(find(i.Var3==1))
            %                     trainedModel=ModelGUI.filed{modelnumb};
            field = fieldnames(rawData);
            name_i=field{datanumb};
            current=getfield(rawData, name_i)
            xaxis=[1:length(current)]
            assignin('base','current',current);
            plot(app.ch1,current(1,:));
            plot(app.ch2,current(2,:));
            plot(app.ch3,current(3,:));
            plot(app.allch,xaxis,current(1,:),xaxis,current(2,:),xaxis,current(3,:));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '采样';
            app.SerialLabel = uilabel(app.Tab);
            app.SerialLabel.Text = 'Serial';
            app.T_SerialNumber = uitextarea(app.Tab);
            app.T_SerialNumber.Value = {'COM3'};
            app.B_SerialConnect = uibutton(app.Tab, 'push');
            app.B_SerialConnect.ButtonPushedFcn = createCallbackFcn(app, @B_SerialConnectButtonPushed, true);
            app.B_SerialConnect.Text = 'Get';
            app.F_Dispaly = uiaxes(app.Tab);
            title(app.F_Dispaly, '串口图像显示')
            xlabel(app.F_Dispaly, 'X')
            ylabel(app.F_Dispaly, 'Y')
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, '方位模拟')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.L_datalist = uitable(app.Tab);
            app.L_datalist.ColumnName = {'变量'; '长度'; '字节'; '选中'};
            app.L_datalist.RowName = {};
            app.L_datalist.ColumnEditable = true;
            app.L_datalist.CellSelectionCallback = createCallbackFcn(app, @L_datalistCellSelection, true);
            app.B_GenerateData = uibutton(app.Tab, 'push');
            app.B_GenerateData.ButtonPushedFcn = createCallbackFcn(app, @B_GenerateDataButtonPushed, true);
            app.B_GenerateData.Text = 'Generate';
            app.LabelDropDownLabel = uilabel(app.Tab);
            app.LabelDropDown = uidropdown(app.Tab);
            app.LabelDropDown.Items = {'2', '4', '5', '6', '8'};
            app.LabelDropDown.Value = '8';
            app.L_ModelList = uitable(app.Tab);
            app.L_ModelList.ColumnName = {'模型名称'; 'Percent'; '选中'};
            app.L_ModelList.RowName = {};
            app.L_ModelList.ColumnEditable = true;
            app.B_Control = uibutton(app.Tab, 'push');
            app.B_Control.ButtonPushedFcn = createCallbackFcn(app, @B_ControlButtonPushed, true);
            app.B_Control.Text = '开始控制流程';
            app.UIAxes_3 = uiaxes(app.Tab);
            title(app.UIAxes_3, '标签示意')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.B_Control_2 = uibutton(app.Tab, 'push');
            app.B_Control_2.ButtonPushedFcn = createCallbackFcn(app, @keyboard_ctri, true);
            app.B_Control_2.Text = '键盘控制（无法保存原数据）';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '原数据管理';
            app.ch1 = uiaxes(app.Tab_2);
            title(app.ch1, 'CH1')
            xlabel(app.ch1, 'X')
            ylabel(app.ch1, 'Y')
            app.RawDataList = uitable(app.Tab_2);
            app.RawDataList.ColumnName = {'名称'; '长度'; '字节'; '选中'};
            app.RawDataList.RowName = {};
            app.RawDataList.ColumnEditable = true;
            app.showdata = uibutton(app.Tab_2, 'push');
            app.showdata.ButtonPushedFcn = createCallbackFcn(app, @ShowData, true);
            app.showdata.Text = '显示';
            app.ch2 = uiaxes(app.Tab_2);
            title(app.ch2, 'CH2')
            xlabel(app.ch2, 'X')
            ylabel(app.ch2, 'Y')
            app.ch3 = uiaxes(app.Tab_2);
            title(app.ch3, 'CH3')
            xlabel(app.ch3, 'X')
            ylabel(app.ch3, 'Y')
            app.allch = uiaxes(app.Tab_2);
            title(app.allch, 'ALL')
            xlabel(app.allch, 'X')
            ylabel(app.allch, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end